classdef FilteringApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        ShoworiginalsignalCheckBox     matlab.ui.control.CheckBox
        FilteredSoundStateButton       matlab.ui.control.StateButton
        ApplyFilterButton              matlab.ui.control.Button
        CutoffFrequencykHzEditField    matlab.ui.control.NumericEditField
        CutoffFrequencykHzEditFieldLabel  matlab.ui.control.Label
        FrameLengthEditField           matlab.ui.control.NumericEditField
        FrameLengthEditFieldLabel      matlab.ui.control.Label
        UpperFrequencyLimitkHzEditField  matlab.ui.control.NumericEditField
        UpperFrequencyLimitkHzEditFieldLabel  matlab.ui.control.Label
        FilterOrderEditField           matlab.ui.control.NumericEditField
        FilterOrderEditFieldLabel      matlab.ui.control.Label
        PassbandFrequencykHzEditField  matlab.ui.control.NumericEditField
        PassbandFrequencykHzEditFieldLabel  matlab.ui.control.Label
        LowerFrequencyLimitkHzEditField  matlab.ui.control.NumericEditField
        LowerFrequencyLimitkHzEditFieldLabel  matlab.ui.control.Label
        VisualizationTypeDropDown2     matlab.ui.control.DropDown
        VisualizationTypeLabel_2       matlab.ui.control.Label
        FilterTypeDropDown             matlab.ui.control.DropDown
        FilterTypeDropDownLabel        matlab.ui.control.Label
        ErrorTextLabel                 matlab.ui.control.Label
        SoundStateButton               matlab.ui.control.StateButton
        VisualizeDataButton            matlab.ui.control.Button
        VisualizationTypeDropDown      matlab.ui.control.DropDown
        VisualizationTypeLabel         matlab.ui.control.Label
        SelectYourDataDropDown         matlab.ui.control.DropDown
        SelectYourDataDropDownLabel    matlab.ui.control.Label
        UIAxes2                        matlab.ui.control.UIAxes
        UIAxes                         matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        CommuteHome
        CommuteToWork
        CurrentData
        CurrentDataName
        SamplingFrequency
        VisualizationType
        FilteredVisualizationType
        FilterType
        FilterOrder = 1;
        CutoffFrequency = 0;
        FrameLength = 1;
        PassbandFrequency = 1;
        LowerPassbandFrequency = 1;
        UpperPassbandFrequency = 2;
        PlotOriginalSignal = 0;
        Sound
        FilteredData
        FilteredSound
        OriginalPlot
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.ErrorTextLabel.Visible = false;
            app.SoundStateButton.Visible = false;
            app.FilteredSoundStateButton.Visible = false;
            app.PassbandFrequencykHzEditField.Visible = false;
            app.FilterOrderEditField.Visible = false;
            app.FrameLengthEditField.Visible = false;
            app.CutoffFrequencykHzEditField.Visible = false;
            app.UpperFrequencyLimitkHzEditField.Visible = false;
            app.LowerFrequencyLimitkHzEditField.Visible = false;
            app.PassbandFrequencykHzEditFieldLabel.Visible = false;
            app.FilterOrderEditFieldLabel.Visible = false;
            app.FrameLengthEditFieldLabel.Visible = false;
            app.CutoffFrequencykHzEditFieldLabel.Visible = false;
            app.UpperFrequencyLimitkHzEditFieldLabel.Visible = false;
            app.LowerFrequencyLimitkHzEditFieldLabel.Visible = false;
            app.ShoworiginalsignalCheckBox.Visible = false;
            if ~license('test', 'signal_toolbox')
                ToolboxError;
            end
            app.CommuteHome = load("CommuteHome.mat");
            app.CommuteToWork = load("CommuteToWork.mat");
        end

        % Value changed function: SelectYourDataDropDown
        function SelectYourDataDropDownValueChanged(app, event)
            value = app.SelectYourDataDropDown.Value;
            app.SoundStateButton.Visible = false;
            app.FilteredSoundStateButton.Visible = false;
            switch value
                case "Acceleration to Work"
                    app.CurrentData = app.CommuteToWork.Acceleration.Y;
                    app.SamplingFrequency = 10;
                case "Acceleration Home"
                    app.CurrentData = app.CommuteHome.Acceleration.Y;
                    app.SamplingFrequency = 10;
                case "Z-Direction Velocity to Work"
                    app.CurrentData = app.CommuteToWork.AngularVelocity.Z;
                    app.SamplingFrequency = 10;
                case "Z-Direction Velocity Home"
                    app.CurrentData = app.CommuteHome.AngularVelocity.Z;
                    app.SamplingFrequency = 10;
                case "Birdsong"
                    app.SoundStateButton.Visible = true;
                    [y, Fs] = audioread("BirdSong.m4a");
                    app.CurrentData = y(:,2);
                    app.SamplingFrequency = Fs;
            end
            if value == "None"
                app.CurrentDataName = "No Data Selected";
            else
                app.CurrentDataName = value;
            end
        end

        % Value changed function: FilterTypeDropDown
        function FilterTypeDropDownValueChanged(app, event)
            value = app.FilterTypeDropDown.Value;
            switch value
                case "Highpass"
                    app.FilterType = "Highpass";
                    app.PassbandFrequencykHzEditField.Visible = true;
                    app.FilterOrderEditField.Visible = false;
                    app.FrameLengthEditField.Visible = false;
                    app.CutoffFrequencykHzEditField.Visible = false;
                    app.UpperFrequencyLimitkHzEditField.Visible = false;
                    app.LowerFrequencyLimitkHzEditField.Visible = false;
                    app.PassbandFrequencykHzEditFieldLabel.Visible = true;
                    app.FilterOrderEditFieldLabel.Visible = false;
                    app.FrameLengthEditFieldLabel.Visible = false;
                    app.CutoffFrequencykHzEditFieldLabel.Visible = false;
                    app.UpperFrequencyLimitkHzEditFieldLabel.Visible = false;
                    app.LowerFrequencyLimitkHzEditFieldLabel.Visible = false;
                case "Lowpass"
                    app.FilterType = "Lowpass";
                    app.PassbandFrequencykHzEditField.Visible = true;
                    app.FilterOrderEditField.Visible = false;
                    app.FrameLengthEditField.Visible = false;
                    app.CutoffFrequencykHzEditField.Visible = false;
                    app.UpperFrequencyLimitkHzEditField.Visible = false;
                    app.LowerFrequencyLimitkHzEditField.Visible = false;
                    app.PassbandFrequencykHzEditFieldLabel.Visible = true;
                    app.FilterOrderEditFieldLabel.Visible = false;
                    app.FrameLengthEditFieldLabel.Visible = false;
                    app.CutoffFrequencykHzEditFieldLabel.Visible = false;
                    app.UpperFrequencyLimitkHzEditFieldLabel.Visible = false;
                    app.LowerFrequencyLimitkHzEditFieldLabel.Visible = false;
                case "Bandpass"
                    app.FilterType = "Bandpass";
                    app.PassbandFrequencykHzEditField.Visible = false;
                    app.FilterOrderEditField.Visible = false;
                    app.FrameLengthEditField.Visible = false;
                    app.CutoffFrequencykHzEditField.Visible = false;
                    app.UpperFrequencyLimitkHzEditField.Visible = true;
                    app.LowerFrequencyLimitkHzEditField.Visible = true;
                    app.PassbandFrequencykHzEditFieldLabel.Visible = false;
                    app.FilterOrderEditFieldLabel.Visible = false;
                    app.FrameLengthEditFieldLabel.Visible = false;
                    app.CutoffFrequencykHzEditFieldLabel.Visible = false;
                    app.UpperFrequencyLimitkHzEditFieldLabel.Visible = true;
                    app.LowerFrequencyLimitkHzEditFieldLabel.Visible = true;
                case "Butterworth"
                    app.PassbandFrequencykHzEditField.Visible = false;
                    app.FilterOrderEditField.Visible = true;
                    app.FrameLengthEditField.Visible = false;
                    app.CutoffFrequencykHzEditField.Visible = true;
                    app.UpperFrequencyLimitkHzEditField.Visible = false;
                    app.LowerFrequencyLimitkHzEditField.Visible = false;
                    app.PassbandFrequencykHzEditFieldLabel.Visible = false;
                    app.FilterOrderEditFieldLabel.Visible = true;
                    app.FrameLengthEditFieldLabel.Visible = false;
                    app.CutoffFrequencykHzEditFieldLabel.Visible = true;
                    app.UpperFrequencyLimitkHzEditFieldLabel.Visible = false;
                    app.LowerFrequencyLimitkHzEditFieldLabel.Visible = false;
                case "Savitzky-Golay"
                    app.FilterType = "Savitzky-Golay";
                    app.PassbandFrequencykHzEditField.Visible = false;
                    app.FilterOrderEditField.Visible = true;
                    app.FrameLengthEditField.Visible = true;
                    app.CutoffFrequencykHzEditField.Visible = false;
                    app.UpperFrequencyLimitkHzEditField.Visible = false;
                    app.LowerFrequencyLimitkHzEditField.Visible = false;
                    app.PassbandFrequencykHzEditFieldLabel.Visible = false;
                    app.FilterOrderEditFieldLabel.Visible = true;
                    app.FrameLengthEditFieldLabel.Visible = true;
                    app.CutoffFrequencykHzEditFieldLabel.Visible = false;
                    app.UpperFrequencyLimitkHzEditFieldLabel.Visible = false;
                    app.LowerFrequencyLimitkHzEditFieldLabel.Visible = false;
            end
        end

        % Value changed function: FilterOrderEditField
        function FilterOrderEditFieldValueChanged(app, event)
            value = app.FilterOrderEditField.Value;
            app.FilterOrder = value;
        end

        % Value changed function: CutoffFrequencykHzEditField
        function CutoffFrequencykHzEditFieldValueChanged(app, event)
            value = app.CutoffFrequencykHzEditField.Value;
            app.CutoffFrequency = value * 1000;
        end

        % Button pushed function: ApplyFilterButton
        function ApplyFilterButtonPushed(app, event)
            app.ErrorTextLabel.Visible = false;
            app.UIAxes2.Title.String = "Filtered " + app.CurrentDataName + " Data";
            if app.FilterType == "Highpass"
                app.FilteredData = highpass(app.CurrentData, app.PassbandFrequency, app.SamplingFrequency);
            elseif app.FilterType == "Lowpass"
                app.FilteredData = lowpass(app.CurrentData, app.PassbandFrequency, app.SamplingFrequency);
            elseif app.FilterType == "Bandpass"
                app.FilteredData = bandpass(app.CurrentData, [app.LowerPassbandFrequency app.UpperPassbandFrequency], app.SamplingFrequency);
            elseif app.FilterType == "Butterworth"
                [b,a] = butter(app.FilterOrder, app.CutoffFrequency);
                app.FilteredData = filter(b, a, app.CurrentData);
            elseif app.FilterType == "Savitzky-Golay"
                try
                    if app.FilterOrder >= app.FrameLength
                        app.ErrorTextLabel.Text = "Filter order must be smaller than frame length";
                        app.ErrorTextLabel.Visible = true;
                    end
                    app.FilteredData = sgolayfilt(app.CurrentData, app.FilterOrder, app.FrameLength);
                catch ME
                    if ME.identifier == "signal:sgolayfilt:SignalErr"
                        app.ErrorTextLabel.Text = "Frame length must be odd";
                        app.ErrorTextLabel.Visible = true;
                    end
                end
            end     

            if string(app.FilteredVisualizationType) == "Time domain"
                colorbar(app.UIAxes2, 'off');
                app.ShoworiginalsignalCheckBox.Visible = true;
                plot(app.UIAxes2, (1 / app.SamplingFrequency) * 1:length(app.FilteredData), app.FilteredData);
                xlabel(app.UIAxes2, "Time (Seconds)");
                ylabel(app.UIAxes2, "Amplitude");
            elseif string(app.FilteredVisualizationType) == "FFT"
                colorbar(app.UIAxes2, 'off');
                app.ShoworiginalsignalCheckBox.Visible = true;
                y = fft(app.FilteredData);
                p2 = abs(y / length(app.FilteredData));
                p1 = p2(1:(length(app.FilteredData)/2)+1);
                p1(2:end-1) = 2 * p1(2:end-1);
                f = app.SamplingFrequency / length(app.FilteredData) * (0:(length(app.FilteredData) / 2));
                plot(app.UIAxes2, f/1000, p1);
                xlabel(app.UIAxes2, "Frequency (kHz)");
                ylabel(app.UIAxes2, "Amplitude");
            elseif string(app.FilteredVisualizationType) == "Power spectrum"
                try
                    colorbar(app.UIAxes2, 'off');
                    app.ShoworiginalsignalCheckBox.Visible = true;
                    [p, f] = pspectrum(app.FilteredData, app.SamplingFrequency);
                    p = 10 * log10(p);
                    plot(app.UIAxes2, f/1000, p);
                    xlabel(app.UIAxes2, "Frequency (kHz)");
                    ylabel(app.UIAxes2, "Power Spectrum (dB)");
                catch ME
                    if ME.identifier == "MATLAB:pspectrum:expectedFinite"
                        app.ErrorTextLabel.Text = "Your filtered values are not all finite. Make sure your filter order is not too large.";
                        app.ErrorTextLabel.Visible = true;
                    end
                end
            elseif string(app.FilteredVisualizationType) == "Spectrogram"
                app.ShoworiginalsignalCheckBox.Visible = false;
                app.PlotOriginalSignal = false;
                [p, f, t] = pspectrum(app.FilteredData, app.SamplingFrequency, 'spectrogram');
                p = 10 * log10(p);
                surf(app.UIAxes2, t, f/1000, p, 'EdgeColor', 'none');
                axis(app.UIAxes2, 'xy');
                axis(app.UIAxes2, 'tight');
                colormap(app.UIAxes2, parula);
                view(app.UIAxes2, 0, 90);
                cb = colorbar(app.UIAxes2);
                cb.Label.String = "Power Spectrum (dB)";
                xlabel(app.UIAxes2, "Time (Seconds)");
                ylabel(app.UIAxes2, "Frequency (kHz)");
            end

            if app.PlotOriginalSignal
                hold(app.UIAxes2, 'on');
                if string(app.FilteredVisualizationType) == "Time domain"
                    app.OriginalPlot = plot(app.UIAxes2, (1 / app.SamplingFrequency) * 1:length(app.CurrentData), app.CurrentData, 'SeriesIndex', 2);
                elseif string(app.FilteredVisualizationType) == "FFT"
                    y = fft(app.CurrentData);
                    p2 = abs(y / length(app.CurrentData));
                    p1 = p2(1:(length(app.CurrentData)/2)+1);
                    p1(2:end-1) = 2 * p1(2:end-1);
                    f = app.SamplingFrequency / length(app.CurrentData) * (0:(length(app.CurrentData) / 2));
                    app.OriginalPlot = plot(app.UIAxes2, f/1000, p1, 'SeriesIndex', 2);
                elseif string(app.FilteredVisualizationType) == "Power spectrum"
                    [p, f] = pspectrum(app.CurrentData, app.SamplingFrequency);
                    p = 10 * log10(p);
                    app.OriginalPlot = plot(app.UIAxes2, f/1000, p, 'SeriesIndex', 2);
                end
                uistack(app.OriginalPlot, 'bottom');
                legend(app.UIAxes2, {'Original data', 'Filtered data'});
                hold(app.UIAxes2, 'off');
            end

            if app.SelectYourDataDropDown.Value == "Birdsong"
                app.FilteredSoundStateButton.Visible = true;
            end
        end

        % Value changed function: FrameLengthEditField
        function FrameLengthEditFieldValueChanged(app, event)
            value = app.FrameLengthEditField.Value;
            app.FrameLength = value;
        end

        % Value changed function: PassbandFrequencykHzEditField
        function PassbandFrequencykHzEditFieldValueChanged(app, event)
            value = app.PassbandFrequencykHzEditField.Value;
            app.PassbandFrequency = value * 1000;
        end

        % Value changed function: VisualizationTypeDropDown
        function VisualizationTypeDropDownValueChanged(app, event)
            value = app.VisualizationTypeDropDown.Value;
            app.VisualizationType = value;
        end

        % Button pushed function: VisualizeDataButton
        function VisualizeDataButtonPushed(app, event)
            if string(app.VisualizationType) == "Time domain"
                colorbar(app.UIAxes, 'off');
                plot(app.UIAxes, (1 / app.SamplingFrequency) * 1:length(app.CurrentData), app.CurrentData);
                app.UIAxes.Title.String = app.CurrentDataName + " Time Domain Plot";
                xlabel(app.UIAxes, "Time (Seconds)");
                ylabel(app.UIAxes, "Amplitude");
            elseif string(app.VisualizationType) == "FFT"
                colorbar(app.UIAxes, 'off');
                y = fft(app.CurrentData);
                p2 = abs(y / length(app.CurrentData));
                p1 = p2(1:(length(app.CurrentData)/2)+1);
                p1(2:end-1) = 2 * p1(2:end-1);
                f = app.SamplingFrequency / length(app.CurrentData) * (0:(length(app.CurrentData) / 2));
                plot(app.UIAxes, f/1000, p1);
                app.UIAxes.Title.String = app.CurrentDataName + " FFT";
                xlabel(app.UIAxes, "Frequency (kHz)");
                ylabel(app.UIAxes, "Amplitude");
            elseif string(app.VisualizationType) == "Power spectrum"
                colorbar(app.UIAxes, 'off');
                [p, f] = pspectrum(app.CurrentData, app.SamplingFrequency);
                p = 10 * log10(p);
                plot(app.UIAxes, f/1000, p);
                app.UIAxes.Title.String = app.CurrentDataName + " Power Spectrum";
                xlabel(app.UIAxes, "Frequency (kHz)");
                ylabel(app.UIAxes, "Power Spectrum (dB)");
            elseif string(app.VisualizationType) == "Spectrogram"
                [p, f, t] = pspectrum(app.CurrentData, app.SamplingFrequency, 'spectrogram');
                p = 10 * log10(p);
                surf(app.UIAxes, t, f/1000, p, 'EdgeColor', 'none');
                axis(app.UIAxes, 'xy');
                axis(app.UIAxes, 'tight');
                colormap(app.UIAxes, parula);
                view(app.UIAxes, 0, 90);
                cb = colorbar(app.UIAxes);
                cb.Label.String = "Power Spectrum (dB)";
                app.UIAxes.Title.String = app.CurrentDataName + " Spectrogram";
                xlabel(app.UIAxes, "Time (Seconds)");
                ylabel(app.UIAxes, "Frequency (kHz)");
            end
        end

        % Value changed function: VisualizationTypeDropDown2
        function VisualizationTypeDropDown2ValueChanged(app, event)
            value = app.VisualizationTypeDropDown2.Value;
            app.FilteredVisualizationType = value;
        end

        % Value changed function: UpperFrequencyLimitkHzEditField
        function UpperFrequencyLimitkHzEditFieldValueChanged(app, event)
            value = app.UpperFrequencyLimitkHzEditField.Value;
            app.UpperPassbandFrequency = value * 1000;
        end

        % Value changed function: SoundStateButton
        function SoundStateButtonValueChanged(app, event)
            value = app.SoundStateButton.Value;
            if value
                [y, Fs] = audioread("BirdSong.m4a");
                y = y(:,2);
                app.Sound = audioplayer(y, Fs);
                play(app.Sound);
                app.SoundStateButton.Text = "Stop Sound";
            else
                stop(app.Sound);
                app.SoundStateButton.Text = "Play Sound";
            end
        end

        % Value changed function: FilteredSoundStateButton
        function FilteredSoundStateButtonValueChanged(app, event)
            value = app.FilteredSoundStateButton.Value;
            if value
                app.FilteredSound = audioplayer(app.FilteredData, app.SamplingFrequency);
                play(app.FilteredSound);
                app.FilteredSoundStateButton.Text = "Stop Filtered Sound";
            else
                stop(app.FilteredSound);
                app.FilteredSoundStateButton.Text = "Play Filtered Sound";
            end
        end

        % Value changed function: ShoworiginalsignalCheckBox
        function ShoworiginalsignalCheckBoxValueChanged(app, event)
            value = app.ShoworiginalsignalCheckBox.Value;
            app.PlotOriginalSignal = value;
            if value
                hold(app.UIAxes2, 'on');
                if string(app.FilteredVisualizationType) == "Time domain"
                    app.OriginalPlot = plot(app.UIAxes2, (1 / app.SamplingFrequency) * 1:length(app.CurrentData), app.CurrentData, 'SeriesIndex', 2);
                elseif string(app.FilteredVisualizationType) == "FFT"
                    y = fft(app.CurrentData);
                    p2 = abs(y / length(app.CurrentData));
                    p1 = p2(1:(length(app.CurrentData)/2)+1);
                    p1(2:end-1) = 2 * p1(2:end-1);
                    f = app.SamplingFrequency / length(app.CurrentData) * (0:(length(app.CurrentData) / 2));
                    app.OriginalPlot = plot(app.UIAxes2, f/1000, p1, 'SeriesIndex', 2);
                elseif string(app.FilteredVisualizationType) == "Power spectrum"
                    [p, f] = pspectrum(app.CurrentData, app.SamplingFrequency);
                    p = 10 * log10(p);
                    app.OriginalPlot = plot(app.UIAxes2, f/1000, p, 'SeriesIndex', 2);
                end
                uistack(app.OriginalPlot, 'bottom');
                legend(app.UIAxes2, {'Original data', 'Filtered data'});
                hold(app.UIAxes2, 'off');
            else
                delete(app.OriginalPlot);
            end
        end

        % Value changed function: LowerFrequencyLimitkHzEditField
        function LowerFrequencyLimitkHzEditFieldValueChanged(app, event)
            value = app.LowerFrequencyLimitkHzEditField.Value;
            app.LowerPassbandFrequency = value * 1000;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.UIAxes = uiaxes(app.GridLayout);
            title(app.UIAxes, 'No Data Selected')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.GridLayout);
            title(app.UIAxes2, 'No Filter Applied')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.SelectYourDataDropDownLabel = uilabel(app.GridLayout);
            app.SelectYourDataDropDownLabel.Text = 'Select Your Data';
            app.SelectYourDataDropDown = uidropdown(app.GridLayout);
            app.SelectYourDataDropDown.Items = {'None', 'Acceleration (Commute to Work)', 'Acceleration (Commute Home)', 'Z-Direction Velocity (Commute to Work)', 'Z-Direction Velocity (Commute Home)', 'Birdsong'};
            app.SelectYourDataDropDown.ItemsData = {'None', 'Acceleration to Work', 'Acceleration Home', 'Z-Direction Velocity to Work', 'Z-Direction Velocity Home', 'Birdsong'};
            app.SelectYourDataDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectYourDataDropDownValueChanged, true);
            app.SelectYourDataDropDown.Value = 'None';
            app.VisualizationTypeLabel = uilabel(app.GridLayout);
            app.VisualizationTypeLabel.Text = 'Visualization Type';
            app.VisualizationTypeDropDown = uidropdown(app.GridLayout);
            app.VisualizationTypeDropDown.Items = {'None', 'Time domain plot', 'FFT', 'Power spectrum', 'Spectrogram'};
            app.VisualizationTypeDropDown.ItemsData = {'None', 'Time domain', 'FFT', 'Power spectrum', 'Spectrogram'};
            app.VisualizationTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @VisualizationTypeDropDownValueChanged, true);
            app.VisualizationTypeDropDown.Value = 'None';
            app.VisualizeDataButton = uibutton(app.GridLayout, 'push');
            app.VisualizeDataButton.ButtonPushedFcn = createCallbackFcn(app, @VisualizeDataButtonPushed, true);
            app.VisualizeDataButton.Text = 'Visualize Data';
            app.SoundStateButton = uibutton(app.GridLayout, 'state');
            app.SoundStateButton.ValueChangedFcn = createCallbackFcn(app, @SoundStateButtonValueChanged, true);
            app.SoundStateButton.Text = 'Play Sound';
            app.ErrorTextLabel = uilabel(app.GridLayout);
            app.ErrorTextLabel.Text = 'Error Text';
            app.FilterTypeDropDownLabel = uilabel(app.GridLayout);
            app.FilterTypeDropDownLabel.Text = 'Filter Type';
            app.FilterTypeDropDown = uidropdown(app.GridLayout);
            app.FilterTypeDropDown.Items = {'None', 'Highpass', 'Lowpass', 'Bandpass', 'Butterworth', 'Savitzky-Golay'};
            app.FilterTypeDropDown.ItemsData = {'None', 'Highpass', 'Lowpass', 'Bandpass', 'Butterworth', 'Savitzky-Golay'};
            app.FilterTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @FilterTypeDropDownValueChanged, true);
            app.FilterTypeDropDown.Value = 'None';
            app.VisualizationTypeLabel_2 = uilabel(app.GridLayout);
            app.VisualizationTypeLabel_2.Text = 'Visualization Type';
            app.VisualizationTypeDropDown2 = uidropdown(app.GridLayout);
            app.VisualizationTypeDropDown2.Items = {'None', 'Time domain plot', 'FFT', 'Power spectrum', 'Spectrogram'};
            app.VisualizationTypeDropDown2.ItemsData = {'None', 'Time domain', 'FFT', 'Power spectrum', 'Spectrogram'};
            app.VisualizationTypeDropDown2.ValueChangedFcn = createCallbackFcn(app, @VisualizationTypeDropDown2ValueChanged, true);
            app.VisualizationTypeDropDown2.Value = 'None';
            app.LowerFrequencyLimitkHzEditFieldLabel = uilabel(app.GridLayout);
            app.LowerFrequencyLimitkHzEditFieldLabel.Text = 'Lower Frequency Limit (kHz)';
            app.LowerFrequencyLimitkHzEditField = uieditfield(app.GridLayout, 'numeric');
            app.LowerFrequencyLimitkHzEditField.Limits = [1e-16 Inf];
            app.LowerFrequencyLimitkHzEditField.ValueChangedFcn = createCallbackFcn(app, @LowerFrequencyLimitkHzEditFieldValueChanged, true);
            app.LowerFrequencyLimitkHzEditField.Value = 1;
            app.PassbandFrequencykHzEditFieldLabel = uilabel(app.GridLayout);
            app.PassbandFrequencykHzEditFieldLabel.Text = 'Passband Frequency (kHz)';
            app.PassbandFrequencykHzEditField = uieditfield(app.GridLayout, 'numeric');
            app.PassbandFrequencykHzEditField.Limits = [1e-16 Inf];
            app.PassbandFrequencykHzEditField.ValueChangedFcn = createCallbackFcn(app, @PassbandFrequencykHzEditFieldValueChanged, true);
            app.PassbandFrequencykHzEditField.Value = 1;
            app.FilterOrderEditFieldLabel = uilabel(app.GridLayout);
            app.FilterOrderEditFieldLabel.Text = 'Filter Order';
            app.FilterOrderEditField = uieditfield(app.GridLayout, 'numeric');
            app.FilterOrderEditField.Limits = [1 Inf];
            app.FilterOrderEditField.ValueChangedFcn = createCallbackFcn(app, @FilterOrderEditFieldValueChanged, true);
            app.FilterOrderEditField.Value = 1;
            app.UpperFrequencyLimitkHzEditFieldLabel = uilabel(app.GridLayout);
            app.UpperFrequencyLimitkHzEditFieldLabel.Text = 'Upper Frequency Limit (kHz)';
            app.UpperFrequencyLimitkHzEditField = uieditfield(app.GridLayout, 'numeric');
            app.UpperFrequencyLimitkHzEditField.Limits = [1e-16 Inf];
            app.UpperFrequencyLimitkHzEditField.ValueChangedFcn = createCallbackFcn(app, @UpperFrequencyLimitkHzEditFieldValueChanged, true);
            app.UpperFrequencyLimitkHzEditField.Value = 1;
            app.FrameLengthEditFieldLabel = uilabel(app.GridLayout);
            app.FrameLengthEditFieldLabel.Text = 'Frame Length';
            app.FrameLengthEditField = uieditfield(app.GridLayout, 'numeric');
            app.FrameLengthEditField.Limits = [1 Inf];
            app.FrameLengthEditField.ValueChangedFcn = createCallbackFcn(app, @FrameLengthEditFieldValueChanged, true);
            app.FrameLengthEditField.Value = 1;
            app.CutoffFrequencykHzEditFieldLabel = uilabel(app.GridLayout);
            app.CutoffFrequencykHzEditFieldLabel.Text = 'Cutoff Frequency (kHz)';
            app.CutoffFrequencykHzEditField = uieditfield(app.GridLayout, 'numeric');
            app.CutoffFrequencykHzEditField.Limits = [0 1];
            app.CutoffFrequencykHzEditField.ValueChangedFcn = createCallbackFcn(app, @CutoffFrequencykHzEditFieldValueChanged, true);
            app.ApplyFilterButton = uibutton(app.GridLayout, 'push');
            app.ApplyFilterButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyFilterButtonPushed, true);
            app.ApplyFilterButton.Text = 'Apply Filter';
            app.FilteredSoundStateButton = uibutton(app.GridLayout, 'state');
            app.FilteredSoundStateButton.ValueChangedFcn = createCallbackFcn(app, @FilteredSoundStateButtonValueChanged, true);
            app.FilteredSoundStateButton.Text = 'Play Filtered Sound';
            app.ShoworiginalsignalCheckBox = uicheckbox(app.GridLayout);
            app.ShoworiginalsignalCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShoworiginalsignalCheckBoxValueChanged, true);
            app.ShoworiginalsignalCheckBox.Text = 'Show original signal';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FilteringApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end