classdef SimpleFilteringApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        ApplySavitzkyGolayFilterButton  matlab.ui.control.Button
        FrameLengthEditField            matlab.ui.control.NumericEditField
        FrameLengthEditFieldLabel       matlab.ui.control.Label
        FilterOrderEditField            matlab.ui.control.NumericEditField
        FilterOrderEditFieldLabel       matlab.ui.control.Label
        VisualizeDataButton             matlab.ui.control.Button
        SelectYourDataDropDown          matlab.ui.control.DropDown
        SelectYourDataDropDownLabel     matlab.ui.control.Label
        UIAxes                          matlab.ui.control.UIAxes
        UIAxes2                         matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        CommuteHome
        CommuteToWork
        CurrentData
        CurrentDataName
        SamplingFrequency = 10;
        FilterOrder = 1;
        FrameLength = 3;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.CommuteHome = load("CommuteHome.mat");
            app.CommuteToWork = load("CommuteToWork.mat");
        end

        % Value changed function: SelectYourDataDropDown
        function SelectYourDataDropDownValueChanged(app, event)
            value = app.SelectYourDataDropDown.Value;
            switch value
                case "Work"
                    app.CurrentData = app.CommuteToWork.AngularVelocity.Z;
                case "Home"
                    app.CurrentData = app.CommuteHome.AngularVelocity.Z;
            end
            if value == "None"
                app.CurrentDataName = "No Data Selected";
            elseif value == "Work"
                app.CurrentDataName = "Z-Direction Velocity (Commute to Work)";
            elseif value == "Home"
                app.CurrentDataName = "Z-Direction Velocity (Commute Home)";
            end
        end

        % Button pushed function: ApplySavitzkyGolayFilterButton
        function ApplySavitzkyGolayFilterButtonPushed(app, event)
            FilteredData = sgolayfilt(app.CurrentData, app.FilterOrder, app.FrameLength);

            plot(app.UIAxes2, (1 / app.SamplingFrequency) * 1:length(FilteredData), FilteredData);
            app.UIAxes2.Title.String = "Filtered " + app.CurrentDataName;
            xlabel(app.UIAxes2, "Time (Seconds)");
            ylabel(app.UIAxes2, "Amplitude");
        end

        % Button pushed function: VisualizeDataButton
        function VisualizeDataButtonPushed(app, event)
            plot(app.UIAxes, (1 / app.SamplingFrequency) * 1:length(app.CurrentData), app.CurrentData);
            app.UIAxes.Title.String = app.CurrentDataName;
            xlabel(app.UIAxes, "Time (Seconds)");
            ylabel(app.UIAxes, "Amplitude");
        end

        % Value changed function: FilterOrderEditField
        function FilterOrderEditFieldValueChanged(app, event)
            value = app.FilterOrderEditField.Value;
            app.FilterOrder = value;
        end

        % Value changed function: FrameLengthEditField
        function FrameLengthEditFieldValueChanged(app, event)
            value = app.FrameLengthEditField.Value;
            app.FrameLength = value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.UIAxes2 = uiaxes(app.GridLayout);
            title(app.UIAxes2, 'No Filter Applied')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes = uiaxes(app.GridLayout);
            title(app.UIAxes, 'No Data Selected')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.SelectYourDataDropDownLabel = uilabel(app.GridLayout);
            app.SelectYourDataDropDownLabel.Text = 'Select Your Data';
            app.SelectYourDataDropDown = uidropdown(app.GridLayout);
            app.SelectYourDataDropDown.Items = {'None', 'Z-Direction Velocity (Commute to Work)', 'Z-Direction Velocity (Commute Home)'};
            app.SelectYourDataDropDown.ItemsData = {'None', 'Work', 'Home'};
            app.SelectYourDataDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectYourDataDropDownValueChanged, true);
            app.SelectYourDataDropDown.Value = 'None';
            app.VisualizeDataButton = uibutton(app.GridLayout, 'push');
            app.VisualizeDataButton.ButtonPushedFcn = createCallbackFcn(app, @VisualizeDataButtonPushed, true);
            app.VisualizeDataButton.Text = 'Visualize Data';
            app.FilterOrderEditFieldLabel = uilabel(app.GridLayout);
            app.FilterOrderEditFieldLabel.Text = 'Filter Order';
            app.FilterOrderEditField = uieditfield(app.GridLayout, 'numeric');
            app.FilterOrderEditField.Limits = [1 Inf];
            app.FilterOrderEditField.ValueChangedFcn = createCallbackFcn(app, @FilterOrderEditFieldValueChanged, true);
            app.FilterOrderEditField.Value = 1;
            app.FrameLengthEditFieldLabel = uilabel(app.GridLayout);
            app.FrameLengthEditFieldLabel.Text = 'Frame Length';
            app.FrameLengthEditField = uieditfield(app.GridLayout, 'numeric');
            app.FrameLengthEditField.Limits = [3 Inf];
            app.FrameLengthEditField.ValueChangedFcn = createCallbackFcn(app, @FrameLengthEditFieldValueChanged, true);
            app.FrameLengthEditField.Value = 3;
            app.ApplySavitzkyGolayFilterButton = uibutton(app.GridLayout, 'push');
            app.ApplySavitzkyGolayFilterButton.ButtonPushedFcn = createCallbackFcn(app, @ApplySavitzkyGolayFilterButtonPushed, true);
            app.ApplySavitzkyGolayFilterButton.Text = 'Apply Savitzky-Golay Filter';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SimpleFilteringApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end